classdef PropertyPriceCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TotalCostsPanel                 matlab.ui.container.Panel
        finalCostEUR                    matlab.ui.control.Label
        finalCostINR                    matlab.ui.control.Label
        resultLabel                     matlab.ui.control.Label
        CornerPremiumperSqFtEditFieldLabel  matlab.ui.control.Label
        ENFacingChargeperSqFtEditFieldLabel  matlab.ui.control.Label
        AmenitiesInfrastructureEditField_2Label  matlab.ui.control.Label
        FloorRiseCostsEditFieldLabel    matlab.ui.control.Label
        FloorRiseValidityEditFieldLabel  matlab.ui.control.Label
        FinancingPanel                  matlab.ui.container.Panel
        resgistrationCostsLabel         matlab.ui.control.Label
        IndiaLoanNeedLabel              matlab.ui.control.Label
        privateLoanShareLabel           matlab.ui.control.Label
        downPaymentShareLabel           matlab.ui.control.Label
        overheadCost                    matlab.ui.control.Spinner
        OverheadsinoftotalCostSpinnerLabel  matlab.ui.control.Label
        privateLoanLabel                matlab.ui.control.Label
        GermanyPrivateLoanSlider        matlab.ui.control.Slider
        GermanyPrivateLoanSliderLabel   matlab.ui.control.Label
        downPaymentLabel                matlab.ui.control.Label
        DownPaymentRateSlider           matlab.ui.control.Slider
        DownPaymentRateSliderLabel      matlab.ui.control.Label
        CurrencyRatesPanel              matlab.ui.container.Panel
        currencyRateLabel               matlab.ui.control.Label
        RefreshRateButton               matlab.ui.control.Button
        TargetCurrencyDropDown          matlab.ui.control.DropDown
        TargetCurrencyDropDownLabel     matlab.ui.control.Label
        BaseCurrencyDropDown            matlab.ui.control.DropDown
        BaseCurrencyDropDownLabel       matlab.ui.control.Label
        CalculateCostsButton            matlab.ui.control.Button
        CornerPremiumperSqFtEditField   matlab.ui.control.NumericEditField
        cornerSideToggle                matlab.ui.control.CheckBox
        ENFacingChargeperSqFtEditField  matlab.ui.control.NumericEditField
        facingSideToggle                matlab.ui.control.CheckBox
        AmenitiesInfrastructureEditField  matlab.ui.control.NumericEditField
        amenitiesToggle                 matlab.ui.control.CheckBox
        FloorRiseCostsEditField         matlab.ui.control.NumericEditField
        floorRiseCostToggle             matlab.ui.control.CheckBox
        FloorRiseValidityEditField      matlab.ui.control.NumericEditField
        validityToggle                  matlab.ui.control.CheckBox
        PropertyParametersPanel         matlab.ui.container.Panel
        chosenFloorLabel                matlab.ui.control.Label
        FloorSlider                     matlab.ui.control.Slider
        FloorSliderLabel                matlab.ui.control.Label
        CarParkingEditField             matlab.ui.control.NumericEditField
        CarParkingEditFieldLabel        matlab.ui.control.Label
        PriceperSqFtEditField           matlab.ui.control.NumericEditField
        PriceperSqFtLabel               matlab.ui.control.Label
        m2EditFieldLabel                matlab.ui.control.Label
        sqmArea                         matlab.ui.control.NumericEditField
        AreainSqFtEditField             matlab.ui.control.NumericEditField
        AreainSqFtLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        Rates % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isfile('lastCurrencyRate.mat')
                data1 = load('lastCurrencyRate.mat');
                app.Rates = data1.rates;
                app.currencyRateLabel.Text = sprintf('Current Rate %s/%s: %.2f', data1.baseCurrency, data1.targetCurrency, data1.rates);
                app.BaseCurrencyDropDown.Value = data1.baseCurrency;
                app.TargetCurrencyDropDown.Value = data1.targetCurrency;
            else
                app.currencyRateLabel.Text = 'Current Rate: Not Available';
            end

            if isfile('lastValues.mat')
                data2 = load('lastValues.mat');
                app.AreainSqFtEditField.Value = data2.area;
                app.PriceperSqFtEditField.Value = data2.price;
                app.CarParkingEditField.Value = data2.parking;
                app.FloorSlider.Value = data2.floor;
            end

            app.chosenFloorLabel.Text = sprintf('%d',app.FloorSlider.Value);
            app.downPaymentLabel.Text = sprintf('%d',app.DownPaymentRateSlider.Value);
            app.privateLoanLabel.Text = sprintf('%d',app.GermanyPrivateLoanSlider.Value);
        end

        % Button pushed function: CalculateCostsButton
        function CalculateCostsButtonPushed(app, event)
            if ~isempty(app.AreainSqFtEditField.Value) && ~isempty(app.PriceperSqFtEditField.Value)
            costSums = 0;

            area = app.AreainSqFtEditField.Value;
            sqFtPrice = app.PriceperSqFtEditField.Value;

            if app.validityToggle.Value
                floorDifference = app.FloorSlider.Value-app.FloorRiseValidityEditField.Value;
            end

            if app.floorRiseCostToggle.Value
                floorRiseCost = app.FloorRiseCostsEditField.Value;
                if floorDifference == 0
                    floorRiseCostComponent = floorRiseCost;
                    costSums = costSums + floorRiseCostComponent;
                elseif floorDifference > 0
                    floorRiseCostComponent = floorDifference*floorRiseCost;
                    costSums = costSums + floorRiseCostComponent;
                end
            end


            if app.amenitiesToggle.Value
                amenitiesCost = app.AmenitiesInfrastructureEditField.Value;
                costSums = costSums + amenitiesCost;
            end

            if app.facingSideToggle.Value
                ENFacingCost = app.ENFacingChargeperSqFtEditField.Value;
                costSums = costSums + ENFacingCost;
            end

            if app.cornerSideToggle.Value
                cornerPremiumCost = app.CornerPremiumperSqFtEditField.Value;
                costSums = costSums + cornerPremiumCost;
            end

            baseCost = area*(sqFtPrice + costSums);
            totalCost = baseCost + app.CarParkingEditField.Value;

            f1 = formatNUM(totalCost,'inr');
            app.resultLabel.Text = sprintf('%s',f1);
            
            % f1 = sprintf('Total Cost: %.2f ₹', totalCost);
            % f1WithCommas = regexprep(f1, '(?<=\d)(?=(\d{3})+(?!\d))', ',');
            % f1WithCommas = getFormattedNumberINR(f1);          

            downPaymentAmount = round((app.DownPaymentRateSlider.Value*totalCost)/100);
            f2 = formatNUM(downPaymentAmount,'inr');
            app.downPaymentShareLabel.Text = sprintf('Down Payment Amount: %s',f2);

            privateLoanAmount = round(app.GermanyPrivateLoanSlider.Value*app.Rates);
            f3 = formatNUM(privateLoanAmount,'inr');
            app.privateLoanShareLabel.Text = sprintf('Private Loan Amount: %s',f3);
           

            IndiaLoanAmount = round(totalCost - downPaymentAmount - privateLoanAmount);
            f4 = formatNUM(IndiaLoanAmount,'inr');
            app.IndiaLoanNeedLabel.Text = sprintf('India Loan Need: %s',f4);

            registrationCosts = round((totalCost * app.overheadCost.Value)/100);
            f5 = formatNUM(registrationCosts,'inr');
            app.resgistrationCostsLabel.Text = sprintf('Registration Costs: %s',f5);

            finalCostinINR = round(totalCost + registrationCosts);
            f6 = formatNUM(finalCostinINR,'inr');
            app.finalCostINR.Text = sprintf('Final Cost in INR: %s',f6);

            finalCostinEUR = round((totalCost + registrationCosts)/app.Rates);
            f7 = formatNUM(finalCostinEUR,'eur');
            app.finalCostEUR.Text = sprintf('Final Cost in EUR: %s',f7);
            else
                warndlg('Area and Sq. Ft price are mandatory')
                return
            end           


        end

        % Value changed function: validityToggle
        function validityToggleValueChanged(app, event)
            toggleState = app.validityToggle.Value;
            % Enable or disable the numeric edit field based on toggle state
            if toggleState
                app.FloorRiseValidityEditField.Enable = 'on';  % Enable the field
                app.FloorRiseValidityEditField.Editable = 'on'; % Make field editable
            else
                app.FloorRiseValidityEditField.Enable = 'off';  % Disable the field
                app.FloorRiseValidityEditField.Editable = 'off'; % Make field non-editable
            end
        end

        % Value changed function: floorRiseCostToggle
        function floorRiseCostToggleValueChanged(app, event)
            toggleState = app.floorRiseCostToggle.Value;
            if toggleState
                app.FloorRiseCostsEditField.Enable = 'on';  % Enable the field
                app.FloorRiseCostsEditField.Editable = 'on'; % Make field editable
            else
                app.FloorRiseCostsEditField.Enable = 'off';  % Disable the field
                app.FloorRiseCostsEditField.Editable = 'off'; % Make field non-editable
            end
        end

        % Value changed function: amenitiesToggle
        function amenitiesToggleValueChanged(app, event)
            toggleState = app.amenitiesToggle.Value;
            if toggleState
                app.AmenitiesInfrastructureEditField.Enable = 'on'; % Enable the field
                app.AmenitiesInfrastructureEditField.Editable = 'on'; % Make field editable
            else
                app.AmenitiesInfrastructureEditField.Enable = 'off'; % Disable the field
                app.AmenitiesInfrastructureEditField.Editable = 'off'; % Make field non-editable
            end
        end

        % Value changed function: facingSideToggle
        function facingSideToggleValueChanged(app, event)
            toggleState = app.facingSideToggle.Value;
            if toggleState
                app.ENFacingChargeperSqFtEditField.Enable = 'on'; % Enable the field
                app.ENFacingChargeperSqFtEditField.Editable = 'on'; % Make field editable
            else
                app.ENFacingChargeperSqFtEditField.Enable = 'off'; % Disable the field
                app.ENFacingChargeperSqFtEditField.Editable = 'off'; % Make field non-editable
            end

            
        end

        % Value changed function: cornerSideToggle
        function cornerSideToggleValueChanged(app, event)
            toggleState = app.cornerSideToggle.Value;
            if toggleState
                app.CornerPremiumperSqFtEditField.Enable = 'on'; % Enable the field
                app.CornerPremiumperSqFtEditField.Editable = 'on'; % Make field editable
            else
                app.CornerPremiumperSqFtEditField.Enable = 'off'; % Disable the field
                app.CornerPremiumperSqFtEditField.Editable = 'off'; % Make field non-editable
            end
        end

        % Button pushed function: RefreshRateButton
        function RefreshRateButtonPushed(app, event)
            [rates,~,~] = exchangerate(app.BaseCurrencyDropDown.Value,app.TargetCurrencyDropDown.Value);
            app.currencyRateLabel.Text = sprintf('Current %s/%s Rate: %.2f', app.BaseCurrencyDropDown.Value, app.TargetCurrencyDropDown.Value, rates);
            baseCurrency = app.BaseCurrencyDropDown.Value;
            targetCurrency = app.TargetCurrencyDropDown.Value;
            save('lastCurrencyRate.mat',"rates","targetCurrency","baseCurrency")
            app.Rates = rates;
        end

        % Value changed function: FloorSlider
        function FloorSliderValueChanged(app, event)
            value = app.FloorSlider.Value;
            app.chosenFloorLabel.Text = sprintf('%d', value);
        end

        % Value changed function: DownPaymentRateSlider
        function DownPaymentRateSliderValueChanged(app, event)
            value = app.DownPaymentRateSlider.Value;
            app.downPaymentLabel.Text = sprintf('%d',value);
        end

        % Value changed function: GermanyPrivateLoanSlider
        function GermanyPrivateLoanSliderValueChanged(app, event)
            value = app.GermanyPrivateLoanSlider.Value;
            app.privateLoanLabel.Text = sprintf('%d',value);
        end

        % Value changed function: AreainSqFtEditField
        function AreainSqFtEditFieldValueChanged(app, event)
            value = app.AreainSqFtEditField.Value;
            app.sqmArea.Value = value/10.764;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            area = app.AreainSqFtEditField.Value;
            price = app.PriceperSqFtEditField.Value;
            parking = app.CarParkingEditField.Value;
            floor = app.FloorSlider.Value;
            save('lastValues.mat',"area","price","parking","floor")

            delete(app)
        end

        % Value changing function: DownPaymentRateSlider
        function DownPaymentRateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.downPaymentLabel.Text = sprintf('%d',changingValue);
            
        end

        % Value changing function: GermanyPrivateLoanSlider
        function GermanyPrivateLoanSliderValueChanging(app, event)
            changingValue = event.Value;
            app.privateLoanLabel.Text = sprintf('%d',changingValue);
        end

        % Value changing function: FloorSlider
        function FloorSliderValueChanging(app, event)
            changingValue = event.Value;
            app.chosenFloorLabel.Text = sprintf('%d', changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PropertyParametersPanel = uipanel(app.UIFigure);
            app.PropertyParametersPanel.Title = 'Property Parameters';
            app.AreainSqFtLabel = uilabel(app.PropertyParametersPanel);
            app.AreainSqFtLabel.Text = 'Area (in Sq. Ft.)';
            app.AreainSqFtEditField = uieditfield(app.PropertyParametersPanel, 'numeric');
            app.AreainSqFtEditField.Limits = [0 Inf];
            app.AreainSqFtEditField.ValueChangedFcn = createCallbackFcn(app, @AreainSqFtEditFieldValueChanged, true);
            app.sqmArea = uieditfield(app.PropertyParametersPanel, 'numeric');
            app.sqmArea.Limits = [0 Inf];
            app.sqmArea.Editable = 'off';
            app.m2EditFieldLabel = uilabel(app.PropertyParametersPanel);
            app.m2EditFieldLabel.Interpreter = 'tex';
            app.m2EditFieldLabel.Text = 'm^2';
            app.PriceperSqFtLabel = uilabel(app.PropertyParametersPanel);
            app.PriceperSqFtLabel.Text = 'Price (per Sq. Ft.)';
            app.PriceperSqFtEditField = uieditfield(app.PropertyParametersPanel, 'numeric');
            app.PriceperSqFtEditField.Limits = [0 Inf];
            app.CarParkingEditFieldLabel = uilabel(app.PropertyParametersPanel);
            app.CarParkingEditFieldLabel.Text = 'Car Parking';
            app.CarParkingEditField = uieditfield(app.PropertyParametersPanel, 'numeric');
            app.CarParkingEditField.Limits = [0 Inf];
            app.CarParkingEditField.AllowEmpty = 'on';
            app.CarParkingEditField.Placeholder = '0';
            app.FloorSliderLabel = uilabel(app.PropertyParametersPanel);
            app.FloorSliderLabel.Text = 'Floor';
            app.FloorSlider = uislider(app.PropertyParametersPanel);
            app.FloorSlider.Limits = [0 35];
            app.FloorSlider.MajorTicks = [0 5 10 15 20 25 30 35];
            app.FloorSlider.ValueChangedFcn = createCallbackFcn(app, @FloorSliderValueChanged, true);
            app.FloorSlider.ValueChangingFcn = createCallbackFcn(app, @FloorSliderValueChanging, true);
            app.FloorSlider.Step = 1;
            app.FloorSlider.Tag = 'floorChoice';
            app.FloorSlider.Tooltip = {'Enter floor'};
            app.FloorSlider.Value = 5;
            app.chosenFloorLabel = uilabel(app.PropertyParametersPanel);
            app.chosenFloorLabel.Text = '';
            app.validityToggle = uicheckbox(app.UIFigure);
            app.validityToggle.ValueChangedFcn = createCallbackFcn(app, @validityToggleValueChanged, true);
            app.validityToggle.Tag = 'validityToggle';
            app.validityToggle.Text = '';
            app.FloorRiseValidityEditField = uieditfield(app.UIFigure, 'numeric');
            app.FloorRiseValidityEditField.Tag = 'floorRiseValid';
            app.FloorRiseValidityEditField.Editable = 'off';
            app.FloorRiseValidityEditField.Enable = 'off';
            app.FloorRiseValidityEditField.Value = 4;
            app.floorRiseCostToggle = uicheckbox(app.UIFigure);
            app.floorRiseCostToggle.ValueChangedFcn = createCallbackFcn(app, @floorRiseCostToggleValueChanged, true);
            app.floorRiseCostToggle.Tag = 'floorRiseCostToggle';
            app.floorRiseCostToggle.Text = '';
            app.FloorRiseCostsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FloorRiseCostsEditField.Editable = 'off';
            app.FloorRiseCostsEditField.Enable = 'off';
            app.FloorRiseCostsEditField.Value = 25;
            app.amenitiesToggle = uicheckbox(app.UIFigure);
            app.amenitiesToggle.ValueChangedFcn = createCallbackFcn(app, @amenitiesToggleValueChanged, true);
            app.amenitiesToggle.Tag = 'amenitiesToggle';
            app.amenitiesToggle.Text = '';
            app.AmenitiesInfrastructureEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmenitiesInfrastructureEditField.Editable = 'off';
            app.AmenitiesInfrastructureEditField.Enable = 'off';
            app.facingSideToggle = uicheckbox(app.UIFigure);
            app.facingSideToggle.ValueChangedFcn = createCallbackFcn(app, @facingSideToggleValueChanged, true);
            app.facingSideToggle.Tag = 'facingSideToggle';
            app.facingSideToggle.Text = '';
            app.ENFacingChargeperSqFtEditField = uieditfield(app.UIFigure, 'numeric');
            app.ENFacingChargeperSqFtEditField.Editable = 'off';
            app.ENFacingChargeperSqFtEditField.Enable = 'off';
            app.cornerSideToggle = uicheckbox(app.UIFigure);
            app.cornerSideToggle.ValueChangedFcn = createCallbackFcn(app, @cornerSideToggleValueChanged, true);
            app.cornerSideToggle.Tag = 'cornerSideToggle';
            app.cornerSideToggle.Text = '';
            app.CornerPremiumperSqFtEditField = uieditfield(app.UIFigure, 'numeric');
            app.CornerPremiumperSqFtEditField.Editable = 'off';
            app.CornerPremiumperSqFtEditField.Enable = 'off';
            app.CalculateCostsButton = uibutton(app.UIFigure, 'push');
            app.CalculateCostsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCostsButtonPushed, true);
            app.CalculateCostsButton.Tag = 'calculateCost';
            app.CalculateCostsButton.Text = 'Calculate Costs';
            app.CurrencyRatesPanel = uipanel(app.UIFigure);
            app.CurrencyRatesPanel.Title = 'Currency Rates';
            app.BaseCurrencyDropDownLabel = uilabel(app.CurrencyRatesPanel);
            app.BaseCurrencyDropDownLabel.Text = 'Base Currency';
            app.BaseCurrencyDropDown = uidropdown(app.CurrencyRatesPanel);
            app.BaseCurrencyDropDown.Items = {'INR', 'EUR', 'USD'};
            app.BaseCurrencyDropDown.Value = 'EUR';
            app.TargetCurrencyDropDownLabel = uilabel(app.CurrencyRatesPanel);
            app.TargetCurrencyDropDownLabel.Text = 'Target Currency';
            app.TargetCurrencyDropDown = uidropdown(app.CurrencyRatesPanel);
            app.TargetCurrencyDropDown.Items = {'INR', 'EUR', 'USD'};
            app.TargetCurrencyDropDown.Value = 'INR';
            app.RefreshRateButton = uibutton(app.CurrencyRatesPanel, 'push');
            app.RefreshRateButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshRateButtonPushed, true);
            app.RefreshRateButton.Text = 'Refresh Rate';
            app.currencyRateLabel = uilabel(app.CurrencyRatesPanel);
            app.currencyRateLabel.Text = '';
            app.FinancingPanel = uipanel(app.UIFigure);
            app.FinancingPanel.Title = 'Financing';
            app.DownPaymentRateSliderLabel = uilabel(app.FinancingPanel);
            app.DownPaymentRateSliderLabel.Text = 'Down Payment Rate';
            app.DownPaymentRateSlider = uislider(app.FinancingPanel);
            app.DownPaymentRateSlider.MajorTicks = [0 50 100];
            app.DownPaymentRateSlider.ValueChangedFcn = createCallbackFcn(app, @DownPaymentRateSliderValueChanged, true);
            app.DownPaymentRateSlider.ValueChangingFcn = createCallbackFcn(app, @DownPaymentRateSliderValueChanging, true);
            app.DownPaymentRateSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.DownPaymentRateSlider.Step = 1;
            app.DownPaymentRateSlider.Value = 25;
            app.downPaymentLabel = uilabel(app.FinancingPanel);
            app.downPaymentLabel.Text = '';
            app.GermanyPrivateLoanSliderLabel = uilabel(app.FinancingPanel);
            app.GermanyPrivateLoanSliderLabel.Text = 'Germany Private Loan';
            app.GermanyPrivateLoanSlider = uislider(app.FinancingPanel);
            app.GermanyPrivateLoanSlider.Limits = [0 100000];
            app.GermanyPrivateLoanSlider.MajorTicks = [0 50000 100000];
            app.GermanyPrivateLoanSlider.MajorTickLabels = {'0', '50000', '100000'};
            app.GermanyPrivateLoanSlider.ValueChangedFcn = createCallbackFcn(app, @GermanyPrivateLoanSliderValueChanged, true);
            app.GermanyPrivateLoanSlider.ValueChangingFcn = createCallbackFcn(app, @GermanyPrivateLoanSliderValueChanging, true);
            app.GermanyPrivateLoanSlider.MinorTicks = [0 25000 75000];
            app.GermanyPrivateLoanSlider.Value = 80000;
            app.privateLoanLabel = uilabel(app.FinancingPanel);
            app.privateLoanLabel.Text = '';
            app.OverheadsinoftotalCostSpinnerLabel = uilabel(app.FinancingPanel);
            app.OverheadsinoftotalCostSpinnerLabel.Text = 'Overheads in % of total Cost';
            app.overheadCost = uispinner(app.FinancingPanel);
            app.overheadCost.Limits = [0 20];
            app.overheadCost.Value = 6;
            app.downPaymentShareLabel = uilabel(app.FinancingPanel);
            app.downPaymentShareLabel.Text = '';
            app.privateLoanShareLabel = uilabel(app.FinancingPanel);
            app.privateLoanShareLabel.Text = '';
            app.IndiaLoanNeedLabel = uilabel(app.FinancingPanel);
            app.IndiaLoanNeedLabel.Text = '';
            app.resgistrationCostsLabel = uilabel(app.FinancingPanel);
            app.resgistrationCostsLabel.Text = '';
            app.FloorRiseValidityEditFieldLabel = uilabel(app.UIFigure);
            app.FloorRiseValidityEditFieldLabel.Enable = 'off';
            app.FloorRiseValidityEditFieldLabel.Text = 'Floor Rise Validity';
            app.FloorRiseCostsEditFieldLabel = uilabel(app.UIFigure);
            app.FloorRiseCostsEditFieldLabel.Enable = 'off';
            app.FloorRiseCostsEditFieldLabel.Text = 'Floor Rise Costs';
            app.AmenitiesInfrastructureEditField_2Label = uilabel(app.UIFigure);
            app.AmenitiesInfrastructureEditField_2Label.Enable = 'off';
            app.AmenitiesInfrastructureEditField_2Label.Text = 'Amenities & Infrastructure';
            app.ENFacingChargeperSqFtEditFieldLabel = uilabel(app.UIFigure);
            app.ENFacingChargeperSqFtEditFieldLabel.Enable = 'off';
            app.ENFacingChargeperSqFtEditFieldLabel.Text = 'E/ N Facing Charge (per Sq. Ft.)';
            app.CornerPremiumperSqFtEditFieldLabel = uilabel(app.UIFigure);
            app.CornerPremiumperSqFtEditFieldLabel.Enable = 'off';
            app.CornerPremiumperSqFtEditFieldLabel.Text = 'Corner Premium (per Sq. Ft.)';
            app.resultLabel = uilabel(app.UIFigure);
            app.resultLabel.Tag = 'resultLabel';
            app.resultLabel.Text = '';
            app.TotalCostsPanel = uipanel(app.UIFigure);
            app.TotalCostsPanel.Title = 'Total Costs';
            app.finalCostINR = uilabel(app.TotalCostsPanel);
            app.finalCostINR.Text = '';
            app.finalCostEUR = uilabel(app.TotalCostsPanel);
            app.finalCostEUR.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PropertyPriceCalculator

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end